classdef SPectrA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        button_load           matlab.ui.control.Button
        button_save           matlab.ui.control.Button
        status_loaded         matlab.ui.control.Lamp
        status_saved          matlab.ui.control.Lamp
        DatasetsinloadedexperimentLabel  matlab.ui.control.Label
        listbox_status        matlab.ui.control.TextArea
        button_edit           matlab.ui.control.Button
        label_filename        matlab.ui.control.Label
        label_dir             matlab.ui.control.Label
        button_go_to_dir      matlab.ui.control.Button
        button_visualization  matlab.ui.control.Button
        label_name            matlab.ui.control.Label
        button_custom         matlab.ui.control.Button
    end

    properties (Access = public)
        child_app
        app_dir
        file_results
        file_metadata
        file_dir
        filename
        load_last_dir
        tts
        hsms
        datasets_names
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup
            % ----------------------
            % finds the folder of the running processing engine and sets the path
            app.app_dir = strrep(mfilename('fullpath'),mfilename,'');
            cd(app.app_dir);
            
            % add folders to path
            addpath(app.app_dir);
            addpath(genpath(app.app_dir));
            app.load_last_dir = app.app_dir;
        end

        % Button pushed function: button_load
        function DatasetLoad(app, event)
            HomeDatasetLoad(app, event);
        end

        % Button pushed function: button_save
        function DatasetSave(app, event)
            HomeDatasetSave(app, event);
        end

        % Button pushed function: button_go_to_dir
        function OpenFileDir(app, event)
            HomeOpenFileDir(app, event);
        end

        % Button pushed function: button_edit
        function DatasetEdit(app, event)
            HomeDatasetEdit(app, event);
        end

        % Button pushed function: button_visualization
        function ShowVisualization(app, event)
            HomeShowVisualization(app, event);
        end

        % Button pushed function: button_custom
        function ShowCustom(app, event)
            HomeShowCustom(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.button_load = uibutton(app.UIFigure, 'push');
            app.button_load.ButtonPushedFcn = createCallbackFcn(app, @DatasetLoad, true);
            app.button_load.Text = 'Load';
            app.button_save = uibutton(app.UIFigure, 'push');
            app.button_save.ButtonPushedFcn = createCallbackFcn(app, @DatasetSave, true);
            app.button_save.Enable = 'off';
            app.button_save.Text = 'Save';
            app.status_loaded = uilamp(app.UIFigure);
            app.status_saved = uilamp(app.UIFigure);
            app.DatasetsinloadedexperimentLabel = uilabel(app.UIFigure);
            app.DatasetsinloadedexperimentLabel.Text = 'Datasets in loaded experiment:';
            app.listbox_status = uitextarea(app.UIFigure);
            app.button_edit = uibutton(app.UIFigure, 'push');
            app.button_edit.ButtonPushedFcn = createCallbackFcn(app, @DatasetEdit, true);
            app.button_edit.Enable = 'off';
            app.button_edit.Text = 'Edit';
            app.label_filename = uilabel(app.UIFigure);
            app.label_filename.Text = 'File Name';
            app.label_dir = uilabel(app.UIFigure);
            app.label_dir.Text = 'Folder dir';
            app.button_go_to_dir = uibutton(app.UIFigure, 'push');
            app.button_go_to_dir.ButtonPushedFcn = createCallbackFcn(app, @OpenFileDir, true);
            app.button_go_to_dir.Text = '>';
            app.button_visualization = uibutton(app.UIFigure, 'push');
            app.button_visualization.ButtonPushedFcn = createCallbackFcn(app, @ShowVisualization, true);
            app.button_visualization.Enable = 'off';
            app.button_visualization.Text = 'Visualization';
            app.label_name = uilabel(app.UIFigure);
            app.label_name.Text = 'SPectrA 5.0.7';
            app.button_custom = uibutton(app.UIFigure, 'push');
            app.button_custom.ButtonPushedFcn = createCallbackFcn(app, @ShowCustom, true);
            app.button_custom.Enable = 'off';
            app.button_custom.Text = 'Custom Visualization';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPectrA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end